classdef segmentationEvaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationEvaluatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        EraseButton                    matlab.ui.control.Button
        ConfirmLamp                    matlab.ui.control.Lamp
        ConfirmLampLabel               matlab.ui.control.Label
        DrawLamp                       matlab.ui.control.Lamp
        DrawLampLabel                  matlab.ui.control.Label
        ProblemIDDropDown              matlab.ui.control.DropDown
        ChooseCaseLabel                matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ConfirmButton                  matlab.ui.control.Button
        DrawButton                     matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        LeaderboardButton              matlab.ui.control.Button
        DiceScoreLabel                 matlab.ui.control.Label
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        LevelLamp                      matlab.ui.control.Lamp
        LevelLampLabel                 matlab.ui.control.Label
        Gauge                          matlab.ui.control.Gauge
        GaugeLabel                     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % display image - this is different from the read image due to
        % overlays and such.
        imageToDisplay = imread("data/candidate_1_c3sag128.png");

        % the image data to run segmentation on
        imageToSegment = imread("data/candidate_1_c3sag128.png");   
        
        % mask corresponding to the image
        segmentMask = imread("data/label_1_c3sag128.png");

        % human-drawn segment
        humanSegmentMask

        % instruction above the image
        instructionTitle = "Label the brainstem - click on 'Draw' to start!";
        
        % Store the current case number.
        currentCase = "Problem01";

        % Handle for new segmentations
        newFH

        % Flag for draw or erase mode
        mode = "none";
        
        % Location to save images for leaderboard
        savelocation = "data/savedsegs";
    end
    
    methods (Access = private)
        
        function updateGauge(app, value)
            app.Gauge.Value = value;
        end
        
        function askToSaveSegmentation(app)
            msg = 'Saving this segmentation adds it to the leaderboard.';
            title = 'Confirm Save';

            saveDialog = uifigure('Name', title, 'Visible', 'off');
            saveDialog.WindowStyle = 'modal';
            saveDialog.Position = [100 100 400 200];
            
            lbl = uilabel(saveDialog, ...
                'Position',[50 150 300 22]);
            lbl.Text = msg;

            name_lbl = uilabel(saveDialog, ...
                'Position',[50 100 150 22]);
            name_lbl.Text = "Enter Unique Name:";

            txt = uieditfield(saveDialog, ...
                'Position',[200 100 150 22]);

            yesBtn = uibutton(saveDialog, 'ButtonPushedFcn', @(btn,event) yesButtonPushed());
            yesBtnX = 50;
            yesBtnY = 50;
            yesBtnWidth = 100;
            yesBtnHeight = 22;
            yesBtn.Position = [yesBtnX yesBtnY yesBtnWidth yesBtnHeight];
            yesBtn.Text = 'Yes';

            noBtn = uibutton(saveDialog,  'ButtonPushedFcn', @(btn,event) noButtonPushed());
            noBtnX = 250;
            noBtnY = 50;
            noBtnWidth = 100;
            noBtnHeight = 22;
            noBtn.Position = [noBtnX noBtnY noBtnWidth noBtnHeight];
            noBtn.Text = 'No';
            saveDialog.Visible = 'on';

            function yesButtonPushed(~)
                app.saveCurrentSegmentation(txt.Value);
                close(saveDialog);
            end

            function noButtonPushed(~)
                close(saveDialog);
            end
        end
        
        function saveCurrentSegmentation(app, unique_name)
            if ~exist(app.savelocation, "dir")
                mkdir(app.savelocation)
            end
            fname = string(string(app.currentCase) + "_" + unique_name + "_" + posixtime(datetime('now'))) + ".png";
            imwrite(app.humanSegmentMask, fullfile(app.savelocation, fname));
        end
        
        function lb = constructLeaderboard(app)
            % already tested that savelocation exists
            files = dir(app.savelocation + filesep + "*.png");
            dsc = {};
            flist = {};
            
            matchedIdx = 1;
            for idx = 1:numel(files)
                fname = files(idx).name;
                fparts = strsplit(fname, "_");
                if strcmp(fparts{1}, app.currentCase)
                    if numel(fparts) == 3
                        unique_name = fparts{2};
                    else
                        unique_name = "";
                        for idx = 2:numel(fparts) - 1
                            unique_name = unique_name + fparts{idx} + "_"; 
                        end
                    end
                    flist{matchedIdx} = unique_name;
                    dsc{matchedIdx} = app.computeMetric(fname);
                    matchedIdx = matchedIdx + 1;
                end
            end
            lb = table(flist', dsc', 'VariableNames',[app.currentCase, "DSC"]);
            lb = sortrows(lb, "DSC", "descend");
        end
        
        function results = computeMetric(app, fname)
            problemID = strsplit(fname, "_");
            problemID = problemID{1};
            im1 = imread(fullfile(app.savelocation, fname));
            switch problemID
                case "Problem01"
                    im2 = imread("data/label_1_c3sag128.png");
                case "Problem02"
                    im2 = imread("data/label_2_c3sag150.png");
                case "Problem03"
                    im2 = imread("data/label_3_c7sag120.png");
                case "Problem04"
                    im2 = imread("data/label_4_c7sag170.png");
                case "Problem05"
                    im2 = imread("data/label_5_c7sag130.png");
            end
            results = app.computeDSC(im1, im2);
        end
        
        function diceCoeff = computeDSC(~, im1, im2)
            im1 = (im1 == true);
            im2 = (im2 == true);
            diceCoeff = 2 * sum(im1 & im2) / (sum(im1) + sum(im2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % When the App opens, load the first case image.
            app.humanSegmentMask = zeros('like', app.segmentMask);
            imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);
            xlabel(app.UIAxes, "");
            ylabel(app.UIAxes, "");
            title(app.UIAxes, app.instructionTitle);

            app.LevelLamp.Color = [0.6350 0.0780 0.1840];
            app.Gauge.Value = 0;
            app.ResultsTextArea.Value = "";

            % Enable the draw boundary button
            app.DrawButton.Enable = 'on';
            app.DrawLamp.Color = "Green";

            % Enable the leaderboard at all times
            app.LeaderboardButton.Enable = 'on';
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: ProblemIDDropDown
        function ProblemIDDropDownValueChanged(app, event)
            value = app.ProblemIDDropDown.Value;

            % Ask to save segmentation to show up on leaderboard
            if any(app.humanSegmentMask(:) > 0)
                app.askToSaveSegmentation();
            end

            if string(value) ~= string(app.currentCase)
                fig = app.SegmentationEvaluatorUIFigure;
                msg = 'Changing problem will overwrite previous segmentations.';
                title = 'Confirm problem change';
                selection = uiconfirm(fig, msg, title, ...
                            'Options',{'OK', 'Cancel'}, ...
                            'DefaultOption', 1, 'CancelOption', 2);
                if strcmp(selection, "OK")
                    switch value
                        case "Problem01"
                            app.imageToSegment = imread("data/candidate_1_c3sag128.png");
                            app.segmentMask = imread("data/label_1_c3sag128.png");                
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                        
                        case "Problem02"
                            app.imageToSegment = imread("data/candidate_2_c3sag150.png");
                            app.segmentMask = imread("data/label_2_c3sag150.png");
                            app.instructionTitle = "Label the tumor - click on 'Draw' to start!";
        
                        case "Problem03"
                            app.imageToSegment = imread("data/candidate_3_c7sag120.png");
                            app.segmentMask = imread("data/label_3_c7sag120.png");
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
        
                        case "Problem04"
                            app.imageToSegment = imread("data/candidate_4_c7sag170.png");
                            app.segmentMask = imread("data/label_4_c7sag170.png");
                            app.instructionTitle = "Label the tumor - click on 'Draw' to start!";
        
                        case "Problem05"
                            app.imageToSegment = imread("data/candidate_5_c7sag130.png");
                            app.segmentMask = imread("data/label_5_c7sag130.png");
                            app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                        otherwise
                            error("Invalid entry in problem drop down - restart the App please!");
                    end
                    app.imageToDisplay = im2uint8(rescale(app.imageToSegment));
                    app.currentCase = value;
                    startupFcn(app);
                    app.DrawLamp.Color = "Green";
                else
                    app.ProblemIDDropDown.Value = app.currentCase;
                end
            end
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.instructionTitle = "Click and drag to draw a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.EraseButton.Enable = 'off';
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "draw";
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if isvalid(app.newFH)
                newMask = createMask(app.newFH);
                if strcmp(app.mode, "draw")
                    app.humanSegmentMask = app.humanSegmentMask | newMask;
                elseif strcmp(app.mode, "erase")
                    app.humanSegmentMask = app.humanSegmentMask & ~newMask;
                end

                diceCoeff = app.computeDSC(app.humanSegmentMask, app.segmentMask);
                app.updateGauge(diceCoeff * 100);

                app.imageToDisplay = labeloverlay(im2uint8(rescale(app.imageToSegment)), uint8(app.humanSegmentMask));
                imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);

                if app.Gauge.Value >= 85
                    app.LevelLamp.Color = [0.4660 0.6740 0.1880];
                    status = sprintf("Great job, your score is %f, what next?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                elseif app.Gauge.Value < 85 && app.Gauge.Value >= 70
                    app.LevelLamp.Color = [0.9290 0.6940 0.1250];
                    status = sprintf("Good progress, your score is %f, can you do better?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                else
                    app.LevelLamp.Color = [0.6350 0.0780 0.1840];
                    status = sprintf("There's some way to go: your score is %f, try again?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                end

                delete(app.newFH)
                app.newFH = [];
                app.SaveButton.Enable = 'on';
                app.EraseButton.Enable = 'on';
                app.ConfirmButton.Enable = 'off';
                app.DrawButton.Enable = 'on';
                app.ConfirmLamp.Color = "Red";
                app.instructionTitle = "Click 'Draw' or 'Erase' to make more changes!";
                title(app.UIAxes, app.instructionTitle);
            else
                app.ConfirmButton.Enable = 'off';
                app.ConfirmLamp.Color = "Red";

                % Nothing new to erase, so do not enable it.
                app.DrawButton.Enable = 'on';
            end
            app.mode = "none";
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            app.instructionTitle = "Click and drag to erase a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes, "Color", [0.8500 0.3250 0.0980]);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "erase";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fig = app.SegmentationEvaluatorUIFigure;
            msg = 'Resetting will delete all existing segmentations.';
            title = 'Confirm reset';
            selection = uiconfirm(fig, msg, title, ...
                        'Options',{'OK', 'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
            if strcmp(selection, "OK")
                app.imageToSegment = imread("data/candidate_1_c3sag128.png");
                app.imageToDisplay = app.imageToSegment;
                app.segmentMask = imread("data/label_1_c3sag128.png");
                app.humanSegmentMask = zeros("like", app.segmentMask);
                app.EraseButton.Enable = 'off';
                app.SaveButton.Enable = 'off';

                app.instructionTitle = "Label the brainstem - click on 'Draw' to start!";
                app.ProblemIDDropDown.Value = "Problem01";
                startupFcn(app);
                app.DrawLamp.Color = "Green";
                app.ResultsTextArea.Value = "";
                app.LevelLamp.Color = [0.6350 0.0780 0.1840];
            end
        end

        % Button pushed function: LeaderboardButton
        function LeaderboardButtonPushed(app, event)
            if ~exist(app.savelocation, "dir")
                message = {'No saved segmentations!','Please save some segmentations before looking here!'};
                uialert(app.SegmentationEvaluatorUIFigure, message, 'Warning', 'Icon', 'warning');
            else
                fig = uifigure;
                t = app.constructLeaderboard();
                uitable(fig, 'Data', t, 'position', [5, 5, fig.Position(3) - 5, fig.Position(4) - 5]);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            askToSaveSegmentation(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SegmentationEvaluatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationEvaluatorUIFigure = uifigure('Visible', 'off');
            app.SegmentationEvaluatorUIFigure.Name = 'Segmentation Evaluator';
            app.SegmentationEvaluatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SegmentationEvaluatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Text = 'Draw';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm ';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChooseCaseLabel = uilabel(app.LeftPanel);
            app.ChooseCaseLabel.Text = 'Problem ID:';
            app.ProblemIDDropDown = uidropdown(app.LeftPanel);
            app.ProblemIDDropDown.Items = {'Problem01', 'Problem02', 'Problem03', 'Problem04', 'Problem05'};
            app.ProblemIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemIDDropDownValueChanged, true);
            app.ProblemIDDropDown.Placeholder = 'empty';
            app.ProblemIDDropDown.Value = 'Problem01';
            app.DrawLampLabel = uilabel(app.LeftPanel);
            app.DrawLampLabel.Text = 'Draw';
            app.DrawLamp = uilamp(app.LeftPanel);
            app.ConfirmLampLabel = uilabel(app.LeftPanel);
            app.ConfirmLampLabel.Text = 'Confirm';
            app.ConfirmLamp = uilamp(app.LeftPanel);
            app.EraseButton = uibutton(app.LeftPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Enable = 'off';
            app.EraseButton.Text = 'Erase';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.GaugeLabel = uilabel(app.RightPanel);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.RightPanel, 'circular');
            app.LevelLampLabel = uilabel(app.RightPanel);
            app.LevelLampLabel.Text = 'Level: ';
            app.LevelLamp = uilamp(app.RightPanel);
            app.ResultsTextAreaLabel = uilabel(app.RightPanel);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.RightPanel);
            app.DiceScoreLabel = uilabel(app.RightPanel);
            app.DiceScoreLabel.Text = 'Dice Score';
            app.LeaderboardButton = uibutton(app.RightPanel, 'push');
            app.LeaderboardButton.ButtonPushedFcn = createCallbackFcn(app, @LeaderboardButtonPushed, true);
            app.LeaderboardButton.Text = 'Leaderboard';
            % Show the figure after all components are created
            app.SegmentationEvaluatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationEvaluator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SegmentationEvaluatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SegmentationEvaluatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationEvaluatorUIFigure)
        end
    end
end